classdef FittingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        k_linSlider         matlab.ui.control.Slider
        k_linSliderLabel    matlab.ui.control.Label
        kALabel             matlab.ui.control.Label
        kVLabel             matlab.ui.control.Label
        kLLabel             matlab.ui.control.Label
        kYLabel             matlab.ui.control.Label
        kFLabel             matlab.ui.control.Label
        L0Label             matlab.ui.control.Label
        k_expLabel          matlab.ui.control.Label
        ALabel              matlab.ui.control.Label
        OptimizeButton      matlab.ui.control.Button
        kASlider            matlab.ui.control.Slider
        kASliderLabel       matlab.ui.control.Label
        ModelDropDown       matlab.ui.control.DropDown
        ModelDropDownLabel  matlab.ui.control.Label
        LoadButton          matlab.ui.control.Button
        kVSlider            matlab.ui.control.Slider
        kVSliderLabel       matlab.ui.control.Label
        kLSlider            matlab.ui.control.Slider
        kLSliderLabel       matlab.ui.control.Label
        L0Slider            matlab.ui.control.Slider
        L0SliderLabel       matlab.ui.control.Label
        k_expSlider         matlab.ui.control.Slider
        k_expSliderLabel    matlab.ui.control.Label
        ASlider             matlab.ui.control.Slider
        ASliderLabel        matlab.ui.control.Label
        kYSlider            matlab.ui.control.Slider
        kYSliderLabel       matlab.ui.control.Label
        kFSlider            matlab.ui.control.Slider
        kFSliderLabel       matlab.ui.control.Label
        plot8               matlab.ui.control.UIAxes
        plot7               matlab.ui.control.UIAxes
        plot6               matlab.ui.control.UIAxes
        compplot            matlab.ui.control.UIAxes
        fitplot             matlab.ui.control.UIAxes
        plot3               matlab.ui.control.UIAxes
        plot2               matlab.ui.control.UIAxes
        plot1               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ExpData % Description
        Parameters % Description
    end
    
    methods (Access = private)
        
        function plotFigs(app)
            % make gray color for plots
            gray = [.5 .5 .5];
            
            % load data
            IFR = app.ExpData.IFR;
            Fmt = app.ExpData.Fmt;
            Ymt = app.ExpData.ymt;
            Lmt = app.ExpData.Lmt;
            Vmt = app.ExpData.vmt;
            Amt = app.ExpData.amt;
            Lf = app.ExpData.Lf;
            Vf = app.ExpData.vf;
            Af = app.ExpData.af;
            st = app.ExpData.spiketimes;
            time = app.ExpData.time;
            
            % get gains from sliders
            A = app.ASlider.Value;
            k_exp = app.k_expSlider.Value;
            k_lin = app.k_linSlider.Value;
            L0 = app.L0Slider.Value;
            kF = app.kFSlider.Value;
            kY = app.kYSlider.Value;
            kL = app.kLSlider.Value;
            kV = app.kVSlider.Value;
            kAcc = app.kASlider.Value;
            
            % compute NC and contractile force and yank
            Fnc = A*exp(k_exp*(Lmt - L0)) + k_lin*(Lmt - L0);
            Fc = Fmt - Fnc;
            Ync = A*k_exp*exp(k_exp*(Lmt - L0)).*Vmt + k_lin*Vmt;
            Yc = Ymt - Ync;
            
            if strcmp(app.ModelDropDown.Value, "Kinetics")
                y11 = Lmt;
                y12 = Lf;
                y21 = Fmt;
                y22 = Fnc;
                y23 = Fc;
                y31 = Ymt;
                y32 = Ync;
                y33 = Yc;
                
                f = kF*Fc + kY*Yc;
                
                c1 = kF*Fc;
                c2 = kY*Yc;
                
                y1 = [y11 y12];
                y2 = [y21 y22 y23];
                y3 = [y31 y32 y33];
                c = [c1 c2 f];
                
                L1 = {'L_{MTU}', 'L_Fascicle'};
                L2 = {'F_{MTU}', 'F_{NC}', 'F_C'};
                L3 = {'Y_{MTU}', 'Y_{NC}', 'Y_C'};
                L4 = {'Predictor', 'IFR'};
                L5 = {'F comp.', 'Y comp.', 'Predictor'};
                colors1 = {'k'; 'm'};
                colors2 = {'k'; gray; 'b'};
                colors3 = {'k'; gray; 'g'};
                colors5 = {'b'; 'g'; 'r'};
                
            elseif strcmp(app.ModelDropDown.Value, "MTU kinematics")
                y1 = Lmt;
                y2 = Vmt;
                y3 = Amt;
                
                l_rectif = Lmt;
                l_rectif(l_rectif < 0) = 0;
                v_rectif = Vmt;
                v_rectif(v_rectif < 0) = 0;
                a_rectif = Amt;
                a_rectif(a_rectif < 0) = 0;
                
                f = kL*l_rectif + kV*v_rectif + kAcc*a_rectif;
                
                c1 = kL*l_rectif;
                c2 = kV*v_rectif;
                c3 = kAcc*a_rectif;
                
                c = [c1 c2 c3 f];
                
                L1 = {'L_{MTU}'};
                L2 = {'V_{MTU}'};
                L3 = {'A_{MTU}'};
                L4 = {'Predictor', 'IFR'};
                L5 = {'L comp.', 'V comp.', 'A comp.', 'Predictor'};
                colors1 = {gray};
                colors2 = {'b'};
                colors3 = {'g'};
                colors5 = {gray; 'b'; 'g'; 'r'};
                
            elseif strcmp(app.ModelDropDown.Value, "Fascicle kinematics")
                y1 = Lf;
                y2 = Vf;
                y3 = Af;
                
                f = kL*Lf + kV*Vf + kAcc*Af;
                
                l_rectif = Lf;
                l_rectif(l_rectif < 0) = 0;
                v_rectif = Vf;
                v_rectif(v_rectif < 0) = 0;
                a_rectif = Af;
                a_rectif(a_rectif < 0) = 0;
                
                c1 = kL*l_rectif;
                c2 = kV*v_rectif;
                c3 = kAcc*a_rectif;
                
                c = [c1 c2 c3 f];
                
                L1 = {'L_{Fas}'};
                L2 = {'V_{Fas}'};
                L3 = {'A_{Fas}'};
                L4 = {'Predictor', 'IFR'};
                L5 = {'L comp.', 'V comp.', 'A comp.', 'Predictor'};
                colors1 = {gray};
                colors2 = {'b'};
                colors3 = {'g'};
                colors5 = {gray; 'b'; 'g'; 'r'};
                
            end
            
            f_int = interp1(time, f, st);
            SSR = sum((IFR - f_int).^2);
            SST = sum((IFR - mean(IFR)).^2);
            R2 = 1 - (SSR/SST);
            
            p1 = plot(app.plot1, time, y1);
            legend(app.plot1, L1)
            set(p1, {'color'}, colors1)
            p2 = plot(app.plot2, time, y2);
            legend(app.plot2, L2)
            set(p2, {'color'}, colors2)
            p3 = plot(app.plot3, time, y3);
            legend(app.plot3, L3)
            set(p3, {'color'}, colors3)
            plot(app.fitplot, time, f, 'r', st, IFR, '.k')
            legend(app.fitplot, L4)
            p5 = plot(app.compplot, time, c);
            legend(app.compplot, L5)
            set(p5, {'color'}, colors5)
            if strcmp(app.ModelDropDown.Value, "Kinetics")
                p6 = plot(app.plot6, Lf, y2);
                set(p6, {'color'}, colors2)
                p7 = plot(app.plot7, Lf, y3);
                set(p7, {'color'}, colors3)
            end
            p8 = plot(app.plot8, f, f, 'r', f_int, IFR, '.k');
            app.plot8.Title.String = ['R^{2}: ' num2str(round(R2, 2))];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file,path] = uigetfile('C:\\Users\Jake\Documents\Data\SpindleSpring');
            data = load([path file]);
            app.ExpData = data.procdata;
%             app.Parameters = data.fit;
            
            app.plotFigs();
        end

        % Value changed function: kASlider
        function kASliderValueChanged(app, event)
            cla(app.compplot)
            cla(app.fitplot)
            cla(app.plot8)
            app.plotFigs();
        end

        % Value changing function: kFSlider
        function kFSliderValueChanging(app, event)
            cla(app.fitplot)
            cla(app.compplot)
            cla(app.plot8)
            app.plotFigs();
        end

        % Value changed function: kYSlider
        function kYSliderValueChanged(app, event)
            cla(app.fitplot)
            cla(app.compplot)
            cla(app.plot8)
            app.plotFigs();
        end

        % Value changed function: ASlider
        function ASliderValueChanged(app, event)
            cla(app.plot2)
            cla(app.plot3)
            cla(app.plot6)
            cla(app.plot7)
            cla(app.plot8)
            cla(app.fitplot)
            cla(app.compplot)
            app.ALabel.Text = num2str(app.ASlider.Value);
            app.plotFigs();
        end

        % Value changed function: k_expSlider
        function k_expSliderValueChanged(app, event)
            cla(app.plot2)
            cla(app.plot3)
            cla(app.plot6)
            cla(app.plot7)
            cla(app.plot8)
            cla(app.fitplot)
            cla(app.compplot)
            app.k_expLabel.Text = num2str(app.k_expSlider.Value);
            app.plotFigs();
        end

        % Value changed function: L0Slider
        function L0SliderValueChanged(app, event)
            cla(app.plot2)
            cla(app.plot3)
            cla(app.plot6)
            cla(app.plot7)
            cla(app.plot8)
            cla(app.fitplot)
            cla(app.compplot)
            app.L0Label.Text = num2str(app.L0Slider.Value);
            app.plotFigs();
        end

        % Value changed function: kLSlider
        function kLSliderValueChanged(app, event)
            cla(app.fitplot)
            cla(app.compplot)
            cla(app.plot8)
            app.kLLabel.Text = num2str(app.kLSlider.Value);
            app.plotFigs();
        end

        % Value changed function: kVSlider
        function kVSliderValueChanged(app, event)
            cla(app.fitplot)
            cla(app.compplot)
            cla(app.plot8)
            app.kVLabel.Text = num2str(app.kVSlider.Value);
            app.plotFigs();
        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            cla(app.plot1)
            cla(app.plot2)
            cla(app.plot3)
            cla(app.plot6)
            cla(app.plot7)
            cla(app.plot8)
            cla(app.fitplot)
            cla(app.compplot)
            app.plotFigs();
        end

        % Button pushed function: OptimizeButton
        function Optimize(app, event)
            cla(app.plot1)
            cla(app.plot2)
            cla(app.plot3)
            cla(app.plot6)
            cla(app.plot7)
            cla(app.plot8)
            cla(app.fitplot)
            cla(app.compplot)
            if strcmp(app.ModelDropDown.Value, "Kinetics")
                app.ASlider.Value = app.Parameters.A;
                app.k_expSlider.Value = app.Parameters.k_exp;
                app.L0Slider.Value = app.Parameters.L0;
                app.kFSlider.Value = app.Parameters.kF;
                app.kYSlider.Value = app.Parameters.kY;
                
                app.ALabel.Text = num2str(app.ASlider.Value);
                app.k_expLabel.Text = num2str(app.k_expSlider.Value);
                app.L0Label.Text = num2str(app.L0Slider.Value);
                app.kFLabel.Text = num2str(app.kFSlider.Value);
                app.kYLabel.Text = num2str(app.kYSlider.Value);
            elseif strcmp(app.ModelDropDown.Value, "MTU kinematics")
                
            elseif strcmp(app.ModelDropDown.Value, "Fascicle kinematics")
            end
            app.plotFigs();
        end

        % Value changed function: k_linSlider
        function k_linSliderValueChanged(app, event)
            cla(app.plot2)
            cla(app.plot3)
            cla(app.plot6)
            cla(app.plot7)
            cla(app.plot8)
            cla(app.fitplot)
            cla(app.compplot)
            app.plotFigs();
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.plot1 = uiaxes(app.UIFigure);
            app.plot2 = uiaxes(app.UIFigure);
            app.plot3 = uiaxes(app.UIFigure);
            app.fitplot = uiaxes(app.UIFigure);
            title(app.fitplot, 'Fit')
            xlabel(app.fitplot, 'time')
            app.compplot = uiaxes(app.UIFigure);
            title(app.compplot, 'Components')
            xlabel(app.compplot, 'X')
            ylabel(app.compplot, 'Y')
            app.plot6 = uiaxes(app.UIFigure);
            app.plot7 = uiaxes(app.UIFigure);
            zlabel(app.plot7, 'Z')
            app.plot8 = uiaxes(app.UIFigure);
            app.kFSliderLabel = uilabel(app.UIFigure);
            app.kFSliderLabel.Text = 'kF';
            app.kFSlider = uislider(app.UIFigure);
            app.kFSlider.Limits = [0 1000];
            app.kFSlider.ValueChangingFcn = createCallbackFcn(app, @kFSliderValueChanging, true);
            app.kFSlider.Value = 100;
            app.kYSliderLabel = uilabel(app.UIFigure);
            app.kYSliderLabel.Text = 'kY';
            app.kYSlider = uislider(app.UIFigure);
            app.kYSlider.ValueChangedFcn = createCallbackFcn(app, @kYSliderValueChanged, true);
            app.kYSlider.Value = 10;
            app.ASliderLabel = uilabel(app.UIFigure);
            app.ASliderLabel.Text = 'A';
            app.ASlider = uislider(app.UIFigure);
            app.ASlider.Limits = [0 3];
            app.ASlider.ValueChangedFcn = createCallbackFcn(app, @ASliderValueChanged, true);
            app.k_expSliderLabel = uilabel(app.UIFigure);
            app.k_expSliderLabel.Text = 'k_exp';
            app.k_expSlider = uislider(app.UIFigure);
            app.k_expSlider.Limits = [0 10];
            app.k_expSlider.ValueChangedFcn = createCallbackFcn(app, @k_expSliderValueChanged, true);
            app.k_expSlider.Value = 1;
            app.L0SliderLabel = uilabel(app.UIFigure);
            app.L0SliderLabel.Text = 'L0';
            app.L0Slider = uislider(app.UIFigure);
            app.L0Slider.Limits = [0 3];
            app.L0Slider.ValueChangedFcn = createCallbackFcn(app, @L0SliderValueChanged, true);
            app.kLSliderLabel = uilabel(app.UIFigure);
            app.kLSliderLabel.Text = 'kL';
            app.kLSlider = uislider(app.UIFigure);
            app.kLSlider.Limits = [0 1000];
            app.kLSlider.ValueChangedFcn = createCallbackFcn(app, @kLSliderValueChanged, true);
            app.kVSliderLabel = uilabel(app.UIFigure);
            app.kVSliderLabel.Text = 'kV';
            app.kVSlider = uislider(app.UIFigure);
            app.kVSlider.Limits = [0 30];
            app.kVSlider.ValueChangedFcn = createCallbackFcn(app, @kVSliderValueChanged, true);
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ModelDropDownLabel = uilabel(app.UIFigure);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.UIFigure);
            app.ModelDropDown.Items = {'Kinetics', 'MTU kinematics', 'Fascicle kinematics'};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Value = 'Kinetics';
            app.kASliderLabel = uilabel(app.UIFigure);
            app.kASliderLabel.Text = 'kA';
            app.kASlider = uislider(app.UIFigure);
            app.kASlider.Limits = [0 4];
            app.kASlider.ValueChangedFcn = createCallbackFcn(app, @kASliderValueChanged, true);
            app.OptimizeButton = uibutton(app.UIFigure, 'push');
            app.OptimizeButton.ButtonPushedFcn = createCallbackFcn(app, @Optimize, true);
            app.OptimizeButton.Text = 'Optimize';
            app.ALabel = uilabel(app.UIFigure);
            app.ALabel.Text = 'A';
            app.k_expLabel = uilabel(app.UIFigure);
            app.k_expLabel.Text = 'k_exp';
            app.L0Label = uilabel(app.UIFigure);
            app.L0Label.Text = 'L0';
            app.kFLabel = uilabel(app.UIFigure);
            app.kFLabel.Text = 'kF';
            app.kYLabel = uilabel(app.UIFigure);
            app.kYLabel.Text = 'kY';
            app.kLLabel = uilabel(app.UIFigure);
            app.kLLabel.Text = 'kL';
            app.kVLabel = uilabel(app.UIFigure);
            app.kVLabel.Text = 'kV';
            app.kALabel = uilabel(app.UIFigure);
            app.kALabel.Text = 'kA';
            app.k_linSliderLabel = uilabel(app.UIFigure);
            app.k_linSliderLabel.Text = 'k_lin';
            app.k_linSlider = uislider(app.UIFigure);
            app.k_linSlider.Limits = [0 5];
            app.k_linSlider.ValueChangedFcn = createCallbackFcn(app, @k_linSliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FittingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end