classdef NCfitapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FBGDropDown             matlab.ui.control.DropDown
        FBGDropDownLabel        matlab.ui.control.Label
        SaveProgressGauge       matlab.ui.control.LinearGauge
        SaveProgressGaugeLabel  matlab.ui.control.Label
        LoadButton              matlab.ui.control.Button
        SaveButton              matlab.ui.control.Button
        L0Slider                matlab.ui.control.Slider
        L0SliderLabel           matlab.ui.control.Label
        klinSlider              matlab.ui.control.Slider
        klinSliderLabel         matlab.ui.control.Label
        kexpSlider              matlab.ui.control.Slider
        kexpSliderLabel         matlab.ui.control.Label
        ASlider                 matlab.ui.control.Slider
        ASliderLabel            matlab.ui.control.Label
        AnimalDropDown          matlab.ui.control.DropDown
        AnimalDropDownLabel     matlab.ui.control.Label
        UIAxes3                 matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        D
        
        FF % Description
        YY
        LL
        VV
        TT
        
        Fnc
        Ync
    end
    
    methods (Access = private)
        
        function plotFigs(app)
            cla(app.UIAxes)
            cla(app.UIAxes2)
            cla(app.UIAxes3)
            
            % time series force plot
            plot(app.UIAxes, app.TT, app.FF, "Color", [0 0 0]) % MTU
            hold(app.UIAxes, 'on')
            plot(app.UIAxes, app.TT, app.FF - app.Fnc, "Color", [0 0 1])
            plot(app.UIAxes, app.TT, app.Fnc, "Color", [.4 .4 .4]) % NC comp
            
            % time series yank plot
            plot(app.UIAxes2, app.TT, app.YY, "Color", [0 0 0])
            hold(app.UIAxes2, 'on')
            plot(app.UIAxes2, app.TT, app.YY - app.Ync, "Color", [0.8500 0.3250 0.0980])
            plot(app.UIAxes2, app.TT, app.Ync, "Color", [.4 .4 .4]) % NC comp
            
            % Force/length plot
            plot(app.UIAxes3, app.LL, app.FF, "Color", [0 0 0])
            hold(app.UIAxes3, 'on')
            plot(app.UIAxes3, app.LL, app.FF - app.Fnc, "Color", [0 0 1])
            plot(app.UIAxes3, app.LL, app.Fnc, "Color", [.4 .4 .4]) % NC comp
        end
        
        function computeNC(app)
            A = app.ASlider.Value;
            kexp = app.kexpSlider.Value;
            klin = app.klinSlider.Value;
            L0 = app.L0Slider.Value;
            
            expcomp = A*exp(kexp*(app.LL - L0));
            lincomp = klin*(app.LL - L0);
            
            app.Fnc = expcomp + lincomp;
            app.Ync = expcomp.*app.VV + klin*app.VV;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function AnimalDropDownOpening(app, event)
            An = app.AnimalDropDown.Value;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % reset variables
            app.ASlider.Value = 0;
            app.kexpSlider.Value = 0;
            app.klinSlider.Value = 0;
            app.L0Slider.Value = 0;
            app.SaveProgressGauge.Value = 0;
            
            dirstr = '//cosmic.bme.emory.edu/labs/ting/shared_ting/Jake/SFN/procdataRamps';
            Flevel = app.FBGDropDown.Value;
            myDir = [dirstr Flevel];
            
            % get file names and paths
            workdir = dir(myDir);
            app.D = workdir(3:end);
            
            % select animal
            An = app.AnimalDropDown.Value;
            
            % create matrices to load data for 20 stretches
            n = 1800;
            nAnim = 10;
            app.FF = zeros(nAnim, n);
            app.YY = zeros(nAnim, n);
            app.LL = zeros(1, n);
            app.VV = zeros(1, n);
            app.TT = zeros(1, n);
            
            % load data
            count = 1;
            for ii = 1:numel(app.D)
                if contains(app.D(ii).name, An) && count <= nAnim
                    data = load([myDir filesep app.D(ii).name]);
                    
                    app.FF(count, 1:n) = data.procdata.Fmt(1:n);
                    app.YY(count, 1:n) = data.procdata.ymt(1:n);
                    
                    if count == 1
                        app.TT(count, 1:n) = data.procdata.time(1:n);
                        app.LL(count, 1:n) = data.procdata.Lmt(1:n);
                        app.VV(count, 1:n) = data.procdata.vmt(1:n);
                    end
                    
                    count = count + 1;
                end
            end
            
            app.Fnc = zeros(1, n);
            app.Ync = zeros(1, n);
            
            % plot
            plotFigs(app)
        end

        % Value changed function: ASlider
        function ASliderValueChanged(app, event)
            computeNC(app)
            plotFigs(app)
            
        end

        % Value changed function: kexpSlider
        function kexpSliderValueChanged(app, event)
            computeNC(app)
            plotFigs(app)
        end

        % Value changed function: klinSlider
        function klinSliderValueChanged(app, event)
            computeNC(app)
            plotFigs(app)
        end

        % Value changed function: L0Slider
        function L0SliderValueChanged(app, event)
            computeNC(app)
            plotFigs(app)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            An = app.AnimalDropDown.Value;
%             disp(app.D(1).name)
%             mydir = app.D;
            fileis = contains({app.D.name}, An);
            filetot = sum(fileis);
            count = 1;
            for ii = 1:numel(app.D)
                if contains(app.D(ii).name, An)
                    NC.A = app.ASlider.Value;
                    NC.kexp = app.kexpSlider.Value;
                    NC.klin = app.klinSlider.Value;
                    NC.L0 = app.L0Slider.Value;
                    
                    save([app.D(ii).folder filesep app.D(ii).name], 'NC', '-append')
                    
                    app.SaveProgressGauge.Value = count*100/filetot;
                    count = count + 1;
                end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Force')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Yank')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Force/length')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.AnimalDropDownLabel = uilabel(app.UIFigure);
            app.AnimalDropDownLabel.Text = 'Animal';
            app.AnimalDropDown = uidropdown(app.UIFigure);
            app.AnimalDropDown.Items = {'A100401-21-78', 'A100401-21-82', 'A100401-22-116', 'A100401-22-129', 'A100401-22-96'};
            app.AnimalDropDown.Value = 'A100401-21-78';
            app.ASliderLabel = uilabel(app.UIFigure);
            app.ASliderLabel.Text = 'A';
            app.ASlider = uislider(app.UIFigure);
            app.ASlider.Limits = [0 0.2];
            app.ASlider.ValueChangedFcn = createCallbackFcn(app, @ASliderValueChanged, true);
            app.kexpSliderLabel = uilabel(app.UIFigure);
            app.kexpSliderLabel.Text = 'kexp';
            app.kexpSlider = uislider(app.UIFigure);
            app.kexpSlider.Limits = [0 2];
            app.kexpSlider.ValueChangedFcn = createCallbackFcn(app, @kexpSliderValueChanged, true);
            app.klinSliderLabel = uilabel(app.UIFigure);
            app.klinSliderLabel.Text = 'klin';
            app.klinSlider = uislider(app.UIFigure);
            app.klinSlider.Limits = [0 1];
            app.klinSlider.ValueChangedFcn = createCallbackFcn(app, @klinSliderValueChanged, true);
            app.L0SliderLabel = uilabel(app.UIFigure);
            app.L0SliderLabel.Text = 'L0';
            app.L0Slider = uislider(app.UIFigure);
            app.L0Slider.Limits = [-1 1];
            app.L0Slider.ValueChangedFcn = createCallbackFcn(app, @L0SliderValueChanged, true);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveProgressGaugeLabel = uilabel(app.UIFigure);
            app.SaveProgressGaugeLabel.Text = 'SaveProgress';
            app.SaveProgressGauge = uigauge(app.UIFigure, 'linear');
            app.FBGDropDownLabel = uilabel(app.UIFigure);
            app.FBGDropDownLabel.Text = 'FBG';
            app.FBGDropDown = uidropdown(app.UIFigure);
            app.FBGDropDown.Items = {'100mN', '200mN', '300mN'};
            app.FBGDropDown.Value = '100mN';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NCfitapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end